classdef IsothermFitting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure             % Genetic Algori...
        Constraints           matlab.ui.container.Panel    % Constraints
        FirstDataCheckBox     matlab.ui.control.CheckBox   % Pass through f...
        LastDataCheckBox      matlab.ui.control.CheckBox   % Pass through l...
        PositiveSlopeCheckBox matlab.ui.control.CheckBox   % Ensure dqdP po...
        PositiveQCheckBox     matlab.ui.control.CheckBox   % Ensure q posit...
        PureSpeciesCheckBox   matlab.ui.control.CheckBox   % Ensure pure sp...
        Equations             matlab.ui.container.Panel    % Equations
        LabelEditField5       matlab.ui.control.Label      % dqdP1
        dqdP1Field            matlab.ui.control.EditField  % b0+b1*y+b2*y^2
        Label                 matlab.ui.control.Label      % dqdP2
        dqdP2Field            matlab.ui.control.EditField  % c0+c1*y+c2*y^2
        Label3                matlab.ui.control.Label      % q1
        q1Field               matlab.ui.control.EditField  % b0*y+(1/2)*b1*...
        Label4                matlab.ui.control.Label      % q2
        q2Field               matlab.ui.control.EditField  % c0*(1-y)+(1/2)...
        Label5                matlab.ui.control.Label      % Pars
        ParsField             matlab.ui.control.EditField  % b0,b1,b2,c0,c1,c2
        KpField               matlab.ui.control.Label      % Kp
        KpField2              matlab.ui.control.EditField  % (1-y)*(dqdP1) ...
        Solver                matlab.ui.container.Panel    % Solver
        SolverRunButton       matlab.ui.control.Button     % Run
        InitialGuessCheckBox  matlab.ui.control.CheckBox   % Initial Guess:
        LabelDropDown         matlab.ui.control.Label      % Drop Down:
        DropDown              matlab.ui.control.DropDown   % Genetic algori...
        InitialGuessField     matlab.ui.control.EditField 
        Data                  matlab.ui.container.Panel    % Data
        GetFileButton         matlab.ui.control.Button     % Browse...
        LabelEditField        matlab.ui.control.Label      % Excel File:
        FilePathField         matlab.ui.control.EditField 
        LabelListBox          matlab.ui.control.Label      % Excel Sheet:
        SheetListBox          matlab.ui.control.ListBox    % Select Excel F...
        Label6                matlab.ui.control.Label      % Data Set:
        DataSetListBox        matlab.ui.control.ListBox    % Select Excel F...
        TabGroup              matlab.ui.container.TabGroup % Kp, dqdP, q, R...
        Tab                   matlab.ui.container.Tab      % Kp
        UIAxes                matlab.ui.control.UIAxes    
        Tab2                  matlab.ui.container.Tab      % dqdP
        UIAxes2               matlab.ui.control.UIAxes    
        Tab3                  matlab.ui.container.Tab      % q
        UIAxes3               matlab.ui.control.UIAxes    
        Tab4                  matlab.ui.container.Tab      % Results
        Label7                matlab.ui.control.Label      % Pars
        EditField1            matlab.ui.control.EditField 
        Label8                matlab.ui.control.Label      % SSR
        EditField2            matlab.ui.control.EditField 
        Label9                matlab.ui.control.Label      % q(y=0)
        EditField3            matlab.ui.control.EditField 
        Label10               matlab.ui.control.Label      % q(y=1)
        EditField4            matlab.ui.control.EditField 
        Label11               matlab.ui.control.Label      % dqdP>0
        EditField5            matlab.ui.control.EditField 
    end


    properties (Access = public)
        Fits % Description
    end
    
    methods (Access = private)
        
        function handles = generate_functions(app)
            
            Pars = app.ParsField.Value;
            
            dqdP1 = app.dqdP1Field.Value;
            dqdP2 = app.dqdP2Field.Value;
            dqdP1_fun=str2func((regexprep(['@(y,' Pars ')' dqdP1],{'*','/','\^'},{'.*','./','.^'})));
            dqdP2_fun=str2func((regexprep(['@(y,' Pars ')' dqdP2],{'*','/','\^'},{'.*','./','.^'})));
            
            q1 = app.q1Field.Value;
            q2 = app.q2Field.Value;
            q1_fun=str2func((regexprep(['@(y,' Pars ')' q1],{'*','/','\^'},{'.*','./','.^'})));
            q2_fun=str2func((regexprep(['@(y,' Pars ')' q2],{'*','/','\^'},{'.*','./','.^'})));
            
            Kp = ['@(y,' Pars ')' '(1-y)*(' dqdP1 ') + y*(' dqdP2 ')'];
            Kp_fun = str2func((regexprep(Kp,{'*','/','\^'},{'.*','./','.^'})));
            
            handles.dqdP1=dqdP1_fun;
            handles.dqdP2=dqdP2_fun;
            handles.q1=q1_fun;
            handles.q2=q2_fun;
            handles.Kp=Kp_fun;

        end
        
        function xls = getexceldata(app) %xls.sheetNames,xls.dataSetName,Kp_exp,y_exp,userVals
            xls.filePath=app.FilePathField.Value;
            xls.sheetNames=app.SheetListBox.Value;
            
            [~,~,xls.raw]=xlsread(xls.filePath,xls.sheetNames);
            xls.iRow=6;
            xls.nRows=size(xls.raw,1);
            xls.nCols=size(xls.raw,2);
            
            iCount=1;
            for c=2:xls.nCols
                if ~all(isnan(cell2mat(xls.raw(xls.iRow:end,c))))
                    xls.dataCols(iCount)=c;
                    iCount=iCount+1;
                else
                    xls.nCols=xls.nCols-1;
                end
            end
            xls.dataRows=xls.iRow:xls.nRows;    
            
            xls.dataSetName=app.DataSetListBox.Value;
            
            % organize data, variable index 1 of table = col 1 of spreadsheet
            xls.y = cell2mat(xls.raw(xls.iRow:end,1)); % store entire yCO2 col
            xls.Kp = zeros(xls.nRows-(xls.iRow-1),xls.nCols-1);
            for c=1:xls.nCols-1
                xls.Kp(:,c)=cell2mat(xls.raw(xls.iRow:end,c+1));
            end
            
            % The pure species isotherm values at T,P equaling at infinite dilution
            % of the binaries:
            xls.userVals = cell2mat(xls.raw(xls.iRow-2:xls.iRow-1,2:end));
        end
        
        function handle = foo_nlcon(app,exp)
            
            function [c, ceq] = nlcon(args)
                model = foo_model(app);
                pre=model(exp.y,args);
                
                cBool=[true; app.PositiveSlopeCheckBox.Value; app.PositiveQCheckBox.Value];
                ceqBool=[app.FirstDataCheckBox.Value; app.LastDataCheckBox.Value; app.PureSpeciesCheckBox.Value; app.PureSpeciesCheckBox.Value];
                
                c   = [ sumsqr(exp.Kp-pre.Kp)-0.0000002; 
                        -abs(min(pre.dqdP(:)))+0.001;            %app.PositiveSlopeCheckBox
                        -abs(min([pre.q1(:);pre.q2(:)]))+0.001]; %app.PositiveQCheckBox
                c   = c(cBool);
                
                ceq = [ pre.Kp(1)-exp.Kp(1);        %app.FirstDataCheckBox
                        pre.Kp(end)-exp.Kp(end);    %app.LastDataCheckBox
                        pre.q2(1)-exp.q_pure(1);    %app.PureSpeciesCheckBox
                        pre.q1(end)-exp.q_pure(2)];
                ceq = ceq(ceqBool);
            end
            
            handle=@nlcon;
        end

        function handle = foo_residual(app,exp)
            
            model=foo_model(app);

            
            function val = SSR(args)
                pre=model(exp.y,args);
                val=sumsqr(exp.Kp-pre.Kp);
            end
            
            handle=@SSR;
            
        end

        function pre = foo_model(app)
            
            h=generate_functions(app);
            
            function pre = model(y,args)
                args=num2cell(args(:).',length(args));
                pre.Kp=h.Kp(y,args{:});
                pre.dqdP=[h.dqdP1(y,args{:}).' h.dqdP2(y,args{:}).'];
                pre.q1=h.q1(y,args{:});
                pre.q2=h.q2(y,args{:});
                pre.functions=h;
                pre.pars=args;
            end

            pre=@model;
        end
        
    end
    
    methods (Access = public)

        function results = outputresults(fits)
            results=fits;
        end
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % SolverRunButton button pushed function
        function SolverRunButtonPushed(app)
            app.UIFigure.Name='Genetic Algorithm Fitting: Running...';
            drawnow;
            
            dataSetName=app.DataSetListBox.Items;
            xls=getexceldata(app);
            nPars=length(regexp(app.ParsField.Value,'\w*','match'));
            
            
            for i=1:length(app.DataSetListBox.Items)
                if strcmp(app.DataSetListBox.Value,app.DataSetListBox.Items{i})
                    iSelect=i;
                end
            end
            exp.Kp=xls.Kp(:,iSelect);
            exp.q_pure=xls.userVals(:,iSelect);
            exp.y=xls.y;            
            
            LB = [-Inf -Inf -Inf -Inf -Inf -Inf];
            UB = Inf.*ones(1,nPars);
            
            if strcmp(app.DropDown.Value,'Genetic algorithm')
                options=optimoptions('ga','TolFun',1e-9);
                if ~isempty(app.InitialGuessField.Value)
                    options.InitialPopulationMatrix=str2num(app.InitialGuessField.Value);
                end
                [fitPars,SSR] = ga(foo_residual(app,exp),nPars,[],[],[],[],LB,UB,foo_nlcon(app,exp),options);
            else strcmp(app.DropDown.Value,'Direct search')
                if ~isempty(app.InitialGuessField.Value)
                    x0=str2num(app.InitialGuessField.Value);
                else
                    x0=randn(nPars,1);
                end
                
                opts = optimoptions(@fmincon);
                opts.MaxFunctionEvaluations=10000;
                opts.MaxIterations=10000;
                
                problem = createOptimProblem('fmincon','x0',x0,...
                'objective',foo_residual(app,exp),'lb',LB','ub',UB',...
                'nonlcon',foo_nlcon(app,exp),...
                'options',opts);
                
                gs = GlobalSearch;
                
                [fitPars,SSR]=run(gs,problem);
            end
            y=linspace(0,1,100);
            
            answer = {'no','yes'};
            
            model=foo_model(app);
            pre=model(y,fitPars);
           
            fits.dataSetName  = dataSetName;
            fits.exp          = exp;
            fits.pre          = pre;
            fits.y            = y;
            fits.SSR          = SSR;
            fits.pars         = fitPars;
            app.Fits=fits;
            
            plot(app.UIAxes,exp.y,exp.Kp,'o',y,pre.Kp);
            plot(app.UIAxes2,y,pre.dqdP);
            plot(app.UIAxes3,y,[pre.q1(:) pre.q2(:)]);
            app.UIAxes.YLim=[0 1.10*max(exp.Kp)];
            app.UIAxes.XLim=[0 1];
            %=ylim;3
            %legend(app.UIAxes,'Experimental','Fitted Model','location','southeast');
            %title(sprintf('Data Set: %s (SSR=%.4f)',dataSetName{i},SSR_min));
            %fprintf( ...
  %'\nTrial: %s \nSSR=%.4g \n[B1,B2,B3,C1,C2,C3]=[%s] \ndqdP>0: %s \nq(y=0)=%.4g \nq(y=1)=%.4g \n',...
           % dataSetName{i},SSR_min,sprintf('%#.4g ',fitPars_min),answer{double(min(dqdP(:))>=0) + 1},q(2,1),q(1,end));
            app.EditField1.Value=regexprep(sprintf('%#.4g,',fits.pars),',$','');
            app.EditField2.Value=sprintf('%#.4g',fits.SSR);
            app.EditField3.Value=sprintf('%#.4g (%#.4g)',fits.pre.q2(1),exp.q_pure(1));
            app.EditField4.Value=sprintf('%#.4g (%#.4g)',fits.pre.q1(end),exp.q_pure(2));
            app.EditField5.Value=sprintf('%s',answer{double(min(fits.pre.dqdP(:))>=0) + 1});
            
            app.UIFigure.Name='Genetic Algorithm Fitting';
            drawnow;
        end

        % GetFileButton button pushed function
        function GetFileButtonPushed(app)
            filterSpec={'*.xls;*xlsx','Excel files (*.xls,*.xlsx)'};
            
            [fileName,pathName,~]=uigetfile(filterSpec,'Pick a file','MultiSelect','on');
            xls.filePath=[pathName fileName];
            app.FilePathField.Value=xls.filePath;
            
            [~,xls.sheetNames]=xlsfinfo(xls.filePath);
            app.SheetListBox.Items=xls.sheetNames;
            
            [~,~,xls.raw]=xlsread(xls.filePath,app.SheetListBox.Value);
            xls.iRow=6;
            xls.nRows=size(xls.raw,1);
            xls.nCols=size(xls.raw,2);
            
            iCount=1;
            for c=2:xls.nCols
                if ~all(isnan(cell2mat(xls.raw(xls.iRow:end,c))))
                    xls.dataCols(iCount)=c;
                    iCount=iCount+1;
                else
                    xls.nCols=xls.nCols-1;
                end
            end
            xls.dataRows=xls.iRow:xls.nRows;
            xls.dataSetName=cell(1,xls.nCols-1);
            
            iCount=1;
            for c=xls.dataCols
                xls.dataSetName{1,iCount}=xls.raw{2,c};
                iCount=iCount+1;
            end
            app.DataSetListBox.Items=xls.dataSetName;
        end

        % InitialGuessCheckBox value changed function
        function InitialGuessCheckBoxValueChanged(app)
            value = app.InitialGuessCheckBox.Value;
            if value==true
                app.InitialGuessField.Editable='on';
                app.InitialGuessField.BackgroundColor=[1.00,1.00,1.00];
            else
                app.InitialGuessField.Editable='off';
                app.InitialGuessField.BackgroundColor=[0.94,0.94,0.94];
            end
        end

        % SheetListBox value changed function
        function SheetListBoxValueChanged(app)
            
            [~,~,xls.raw]=xlsread(app.FilePathField.Value,app.SheetListBox.Value);
            xls.iRow=6;
            xls.nRows=size(xls.raw,1);
            xls.nCols=size(xls.raw,2);
            
            iCount=1;
            for c=2:xls.nCols
                if ~all(isnan(cell2mat(xls.raw(xls.iRow:end,c))))
                    xls.dataCols(iCount)=c;
                    iCount=iCount+1;
                else
                    xls.nCols=xls.nCols-1;
                end
            end
            xls.dataRows=xls.iRow:xls.nRows;
            xls.dataSetName=cell(1,xls.nCols-1);
            
            iCount=1;
            for c=xls.dataCols
                xls.dataSetName{1,iCount}=xls.raw{2,c};
                iCount=iCount+1;
            end
            app.DataSetListBox.Items=xls.dataSetName;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Genetic Algorithm Fitting';
            setAutoResize(app, app.UIFigure, true)
            app.Constraints = uipanel(app.UIFigure);
            app.Constraints.Title = 'Constraints';
            app.Constraints.Units = 'pixels';
            app.FirstDataCheckBox = uicheckbox(app.Constraints);
            app.FirstDataCheckBox.Text = 'Pass through first data point';
            app.FirstDataCheckBox.Value = true;
            app.LastDataCheckBox = uicheckbox(app.Constraints);
            app.LastDataCheckBox.Text = 'Pass through last data point';
            app.LastDataCheckBox.Value = true;
            app.PositiveSlopeCheckBox = uicheckbox(app.Constraints);
            app.PositiveSlopeCheckBox.Text = 'Ensure dqdP positivity';
            app.PositiveSlopeCheckBox.Value = true;
            app.PositiveQCheckBox = uicheckbox(app.Constraints);
            app.PositiveQCheckBox.Text = 'Ensure q positivity';
            app.PositiveQCheckBox.Value = true;
            app.PureSpeciesCheckBox = uicheckbox(app.Constraints);
            app.PureSpeciesCheckBox.Text = 'Ensure pure species q';
            app.PureSpeciesCheckBox.Value = true;
            app.Equations = uipanel(app.UIFigure);
            app.Equations.Title = 'Equations';
            app.Equations.Units = 'pixels';
            app.LabelEditField5 = uilabel(app.Equations);
            app.LabelEditField5.Text = 'dqdP1';
            app.dqdP1Field = uieditfield(app.Equations, 'text');
            app.dqdP1Field.Value = 'b0+b1*y+b2*y^2';
            app.Label = uilabel(app.Equations);
            app.Label.Text = 'dqdP2';
            app.dqdP2Field = uieditfield(app.Equations, 'text');
            app.dqdP2Field.Value = 'c0+c1*y+c2*y^2';
            app.Label3 = uilabel(app.Equations);
            app.Label3.Text = 'q1';
            app.q1Field = uieditfield(app.Equations, 'text');
            app.q1Field.Value = 'b0*y+(1/2)*b1*y^2+(1/3)*b2*y^3';
            app.Label4 = uilabel(app.Equations);
            app.Label4.Text = 'q2';
            app.q2Field = uieditfield(app.Equations, 'text');
            app.q2Field.Value = 'c0*(1-y)+(1/2)*c1*(1-y^2)+(1/3)*b2*(1-y^3)';
            app.Label5 = uilabel(app.Equations);
            app.Label5.Text = 'Pars';
            app.ParsField = uieditfield(app.Equations, 'text');
            app.ParsField.Value = 'b0,b1,b2,c0,c1,c2';
            app.KpField = uilabel(app.Equations);
            app.KpField.Text = 'Kp';
            app.KpField2 = uieditfield(app.Equations, 'text');
            app.KpField2.Editable = 'off';
            app.KpField2.Value = '(1-y)*(dqdP1) + y*(dqdP2)';
            app.Solver = uipanel(app.UIFigure);
            app.Solver.Title = 'Solver';
            app.Solver.Units = 'pixels';
            app.SolverRunButton = uibutton(app.Solver, 'push');
            app.SolverRunButton.ButtonPushedFcn = createCallbackFcn(app, @SolverRunButtonPushed);
            app.SolverRunButton.Text = 'Run';
            app.InitialGuessCheckBox = uicheckbox(app.Solver);
            app.InitialGuessCheckBox.ValueChangedFcn = createCallbackFcn(app, @InitialGuessCheckBoxValueChanged);
            app.InitialGuessCheckBox.Text = 'Initial Guess:';
            app.LabelDropDown = uilabel(app.Solver);
            app.LabelDropDown.Text = 'Drop Down:';
            app.DropDown = uidropdown(app.Solver);
            app.DropDown.Items = {'Genetic algorithm', 'Direct search'};
            app.DropDown.Value = 'Genetic algorithm';
            app.InitialGuessField = uieditfield(app.Solver, 'text');
            app.Data = uipanel(app.UIFigure);
            app.Data.Title = 'Data';
            app.Data.Units = 'pixels';
            app.GetFileButton = uibutton(app.Data, 'push');
            app.GetFileButton.ButtonPushedFcn = createCallbackFcn(app, @GetFileButtonPushed);
            app.GetFileButton.Text = 'Browse...';
            app.LabelEditField = uilabel(app.Data);
            app.LabelEditField.Text = 'Excel File:';
            app.FilePathField = uieditfield(app.Data, 'text');
            app.LabelListBox = uilabel(app.Data);
            app.LabelListBox.Text = 'Excel Sheet:';
            app.SheetListBox = uilistbox(app.Data);
            app.SheetListBox.Items = {'Select Excel File...'};
            app.SheetListBox.ValueChangedFcn = createCallbackFcn(app, @SheetListBoxValueChanged);
            app.SheetListBox.Value = 'Select Excel File...';
            app.Label6 = uilabel(app.Data);
            app.Label6.Text = 'Data Set:';
            app.DataSetListBox = uilistbox(app.Data);
            app.DataSetListBox.Items = {'Select Excel File...'};
            app.DataSetListBox.Value = 'Select Excel File...';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'Kp';
            app.UIAxes = uiaxes(app.Tab);
            xlabel(app.UIAxes, 'y');
            ylabel(app.UIAxes, 'K_p');
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'dqdP';
            app.UIAxes2 = uiaxes(app.Tab2);
            xlabel(app.UIAxes2, 'y');
            ylabel(app.UIAxes2, '\partialq/\partialP');
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Units = 'pixels';
            app.Tab3.Title = 'q';
            app.UIAxes3 = uiaxes(app.Tab3);
            xlabel(app.UIAxes3, 'y');
            ylabel(app.UIAxes3, 'q');
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Units = 'pixels';
            app.Tab4.Title = 'Results';
            app.Label7 = uilabel(app.Tab4);
            app.Label7.Text = 'Pars';
            app.EditField1 = uieditfield(app.Tab4, 'text');
            app.Label8 = uilabel(app.Tab4);
            app.Label8.Text = 'SSR';
            app.EditField2 = uieditfield(app.Tab4, 'text');
            app.Label9 = uilabel(app.Tab4);
            app.Label9.Text = 'q(y=0)';
            app.EditField3 = uieditfield(app.Tab4, 'text');
            app.Label10 = uilabel(app.Tab4);
            app.Label10.Text = 'q(y=1)';
            app.EditField4 = uieditfield(app.Tab4, 'text');
            app.Label11 = uilabel(app.Tab4);
            app.Label11.Text = 'dqdP>0';
            app.EditField5 = uieditfield(app.Tab4, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = IsothermFitting()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end